classdef TAM_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        GridLayout8             matlab.ui.container.GridLayout
        ColorScaleTimesLabel    matlab.ui.control.Label
        GridLayout10            matlab.ui.container.GridLayout
        MaxColorTime            matlab.ui.control.NumericEditField
        MinColorTime            matlab.ui.control.NumericEditField
        GridLayout9             matlab.ui.container.GridLayout
        MaxLabel                matlab.ui.control.Label
        MinLabel                matlab.ui.control.Label
        ExportDataButton        matlab.ui.control.Button
        GridLayout7             matlab.ui.container.GridLayout
        T0TimeEditField         matlab.ui.control.NumericEditField
        T0TimeEditFieldLabel    matlab.ui.control.Label
        RectDrawInfo            matlab.ui.control.Label
        SelectRegionButton      matlab.ui.control.Button
        GridLayout6             matlab.ui.container.GridLayout
        RemoveButton            matlab.ui.control.Button
        AddRegionButton         matlab.ui.control.Button
        GridLayout5             matlab.ui.container.GridLayout
        ColorSchemeSwitchLabel  matlab.ui.control.Label
        ColorSchemeSwitch       matlab.ui.control.Switch
        InfoLabel               matlab.ui.control.Label
        LoadDataButton          matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        AvgPlot                 matlab.ui.control.UIAxes
        PixelPlot               matlab.ui.control.UIAxes
        KinPlot                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Accesible by app only
    properties (Access = private)
        DataLoaderDialogApp % Dialog that will open requesting user to input stepsize
        DataTable % Table that holds individual pixel data 
        DataTime % array that hold the time
        FriendlyName % String to show current directoryplotAverage
        PixelCoordinates % n x n associating image point to a color
        CurrentKineticData = [] % Array that holds either pixel data or average of selected pixels
        CurrentSelectedAreaData = [] % Array to hold current selected Data
        T0_Time % Variable to hold the current t0
        T50_Line % t50% line that will be updated for every data set
        DrawnRectangles = [] % hold the Rectangles currently drawn
        SelectedRegions = [] % hold unique rectangle areas i.e. coordinates covered by DrawnRectangles
        t0_line % Line to hold the position of the line
        t0_line_kinPlot % value to hold kinplot t0 line
        ImageSaveData % Variable to hold the imagedata for saving
        fullName % Fullname of app for saving data
        imagePlot % keep track of the image plot
        MinColorIndex % Variable to keep track of the turbo scale indexes
        MaxColorIndex % Variable to keep track of the turbo scale indexes
        
        %% For data with listeners that change, to avoid memory overhead
        CurrentPlot % Keep track of current kinetic Plot
        SelectedRegionPlot % Keep track of selected region
        rectangleID = 1 % Metadata for tracking active and deleted rectangles
        FittedPlot
        
        DataFitFCN % Used to obtain data from time fitting
        
        DataAbove0 % Used to hold data above t0 line
        TimeAbove0 % Used to hold data above
        
        FitX
        FitY
        
        fitFunctions
        fittedX
        fittedY
        
        ActivePixel
    end
    % Accesible by app only
    methods (Access = private)
        
        % Function to plot the overall average data
        function plotAverage(app)           
            % Obtain average data
            average_data = mean(app.DataTable{:,:}, 2);
            
            % Plot data
            plot(app.AvgPlot, app.DataTime, average_data);
            app.AvgPlot.Visible = "on";
            app.KinPlot.Visible = "on";
            
                        
            % Obtain initial t0%
            % Time at which we have highest intensity
            initial_t0 = app.DataTime(average_data == max(average_data));
            
            % Set T0_Time to the initial to
            app.T0_Time = initial_t0;
            % Set initial begining index to T0 time
            app.MinColorIndex = app.T0_Time;
            app.MinColorTime.Value = app.MinColorIndex;
            
            %Set initial ending color index
            app.MaxColorIndex = max(app.DataTime);
            app.MaxColorTime.Value = app.MaxColorIndex;
            % Display value of T0 time
            app.T0TimeEditField.Value = app.T0_Time;
            
            % Draw the t_0 lines on both plots
            app.t0_line = images.roi.Line(app.AvgPlot, "Position",[initial_t0 -1; initial_t0 1] ,"Deletable",false,"InteractionsAllowed","translate");
            app.t0_line_kinPlot = xline(app.KinPlot, app.T0_Time, "LineWidth",1, "DisplayName","");
            % Add listeners to t0_line
            addlistener(app.t0_line, "MovingROI", @(src, evt) handleLineEvent(app, src, evt, app.t0_line_kinPlot));
            % draw translatable line
            addlistener(app.t0_line, "ROIMoved", @(src, evt) handleLineEvent(app, src, evt));
            
            % Draw a y=0 line
            hold(app.AvgPlot, "on");
            yline(app.AvgPlot, 0);
            hold(app.AvgPlot, "off");
        end
        
        % Function to handle ROI Line events
        function handleLineEvent(app, src,evt, t0_line_kinPlot)
            % Obtain event name
            eventName = evt.EventName;
            
            switch(eventName)
                % Line is moving
                case{'MovingROI'}
                    % Set X boundaries
                    if src.Position(1) > max(app.DataTime)
                        % Line cannot go above plot
                        src.Position = [max(app.DataTime) -1; max(app.DataTime) 1];
                    elseif src.Position(1) < min(app.DataTime)
                        % Line cannot go below plot
                        src.Position = [min(app.DataTime) -1; min(app.DataTime) 1];
                    end
                    % Move line in KinPlot to value of ROI line
                    t0_line_kinPlot.Value = src.Position(1);
                    
                    % New value of T_0 is the closest value of where the
                    % line is
                    [~, t0_index] = min(abs(app.DataTime - src.Position(1)));
                    app.T0_Time = app.DataTime(t0_index);
                    app.T0TimeEditField.Value = app.T0_Time;
                    % Calculate new fit functions
                    
                    % Plot new image as time has changed
                    plotImage(app);
                    
                % For computational purposes, new t50 line is drawn after line has moved
                % This can be done above as well, if needed, move the lines in the case 
                % to below 'app.T0_Time = src.Position(1);'
                case{'ROIMoved'}
                    % Plot the t_50 line
                    if ~isempty(app.CurrentKineticData)
                        % When CurrentKineticData is made, T50_Line will be
                        % made too
                        
                        % Make T50 Line for data set
                        app.TimeAbove0 = app.DataTime(app.DataTime >= app.T0_Time);
                        app.DataAbove0 = app.CurrentKineticData(app.DataTime >= app.T0_Time);
                        
                        % UPDATE CURRENT FIT FCN
                        
                        generateFitFCNS(app);
                        
                        
                        % Get intensity at t0
                        intensity = feval(app.fitFunctions{app.ActivePixel}, log10(app.T0_Time));
%                         % Obtain 50% intensity i.e. (closest value to
%                         % intensity / 2
%                         [~, t50_intensityIndex] = min(abs(data - intensity/2));
%                         % Obtain t50 value, time at which intensity is 50%
%                         t50_time = time(t50_intensityIndex);
%                         
%                         % Set value for the line
%                         app.T50_Line.Value = t50_time;
                            
                        app.InfoLabel.Text = "Drawing...";
                        app.InfoLabel.Visible = "on";
                        % Obtain all intensities above t0
                        intensities = feval(app.fitFunctions{app.ActivePixel}, log10(app.TimeAbove0));
                        % get the time index
                        [~, t50_intensityIndex] = min(abs(intensities - intensity/2));
                        % Get the t_50time
                        t50_time = app.TimeAbove0(t50_intensityIndex);
                        
                        app.T50_Line.Value = t50_time;
                        app.InfoLabel.Visible = "off";
                    end   
            end
        end
        
        function plotImage(app)
            %if ~isempty(app.ImagePlot)
                % Delete old ImagePlot and make new one
                %delete(app.ImagePlot);
                %end
                
            % obtain time and data above t0
            % time = app.DataTime(app.DataTime >= app.T0_Time);
            % data = app.DataTable(app.DataTime >= app.T0_Time, :);
            
            % Obtain the t0 and t50 values
%             t0_values = data{time == app.T0_Time, :};
%             t50_values = t0_values / 2;
%             
%             % Get the indeces of the data values closest to the t50_values
%             [~, timeIndices] = min(abs(data{:,:} - t50_values));
%             % The t50 times are obtained using the indices
%             t50_times = time(timeIndices);
%             
%             
            
            %% PLEASE NOT THAT app.fittedFunctions is a 'cell' and must be indexed as such
            % Data already filtered
            % Time already filtered
            % t0_values are intensities at t=0
            % individual pixel T0 intensities transposed to row, cause we
            % have table rows
            
            % t50time = arrayfun(@(y)fzero(@(x)RGfun(x,a,b,c,d)-y,1),t0/2)
            pixelIntensitiesatT0 = arrayfun(@(x)feval(x{:}, log10(app.T0_Time)), app.fitFunctions);
            % all rows of pixel intensities, transposed to row as well
            % allPixelIntensities = arrayfun(@(x)feval(x{:}, app.fittedX), app.fitFunctions, 'UniformOutput', false);
            logt50_times =zeros(length(pixelIntensitiesatT0),1);
            for index=1:length(pixelIntensitiesatT0)
                t50_intensity = pixelIntensitiesatT0(index)/2;
                % feval will generate intensity from time
                % but we want to pass an intensity and get a time
                %logt50_times(index) = fzero(app.fitFunctions{index}, 1) - t50_intensity; 
                %t50time = arrayfun(fzero(cfitOBJ,1))-t0/2
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                t50_time_evaluator = @(x)t50_intensity - app.fitFunctions{index}(x);
                logt50_times(index) = fzero(t50_time_evaluator, log10(app.T0_Time));
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            end
            
            t50_times = 10.^logt50_times;
            t0_values = pixelIntensitiesatT0;
            % Get current colorScheme
            colorScheme = app.ColorSchemeSwitch.Value;
            
            % Use default turbo colormap
            colorMap = turbo();
            
            % Since data was sorted in same way as when pixel Array was
            % made, we can simple perform operations on the data table
            % we round the interpolation as table indices cannot be decimal
            % we interpolate the min and max t50 times to between 30 (a
            % value chosen by Robert
            %%%%% ASK WHY VALUE WAS CHOSEN, I'M NOT SURE
            % We then iterpolate the logarithms of the times
            %%% ASK ABOUT THIS ENTIRE PROCESS TBH, I DON'T KNOW 
            %%% BUT HEY, IF IT AIN'T BROKEN DON'T FIX IT
            % colorIndices = round(interp1(log10([min(t50_times), max(t50_times)]), [30,length(colorMap)], log10(t50_times)));
            
            % Ensure maximun index covers highest t50 time
            if app.MaxColorIndex < max(t50_times)
                app.MaxColorIndex = max(t50_times);
                app.MaxColorTime.Value = app.MaxColorIndex;
            end
            
            % Ensure Mininum Index covers lowest t50 time
            if app.MinColorIndex > min(t50_times)
                app.MinColorIndex = min(t50_times);
                app.MinColorTime.Value = app.MinColorIndex;
            end
            
            colorIndices = round(interp1(log10([app.MinColorIndex, app.MaxColorIndex]), [30,length(colorMap)], log10(t50_times)));
            
            colors = colorMap(colorIndices, :);
            
            % If using intesity derived colorscheme
            if colorScheme == "Intensity Derived"
                % Convert colors to HSV
                colorsHSV = rgb2hsv(colors);
                % Obtain new brightness value
                new_V = interp1([0 max(abs(t0_values))], [0,1], abs(t0_values));
                % Set the new brightness values 
                colorsHSV(:, 3) = new_V;
                % Rewrite colors
                colors = hsv2rgb(colorsHSV);
            end
            % Generate m x m array same way as in making the image data
            % imageData = pagetranspose(reshape(colors, [sqrt(length(colors)), sqrt(length(colors)), 3]));
            imageData = reshape(colors, [sqrt(length(colors)), sqrt(length(colors)), 3]);
            % Plot image
            app.imagePlot = image(app.PixelPlot,imageData);
            % Set Y axis to ascending
            set(app.PixelPlot,'YDir','normal');
            % Image for saving
            app.ImageSaveData = imageData;
   
            % Show plot
            app.PixelPlot.Visible = "on";
            % Add listener for click events
            set(app.imagePlot, "ButtonDownFcn", @(~, evt)imageClicked(app, evt));
            
            % After image is drawn, enable Select region button
            app.SelectRegionButton.Enable = "on";
        end
        % Function to handle when image plot is clicked
        function imageClicked(app, evt)
            
            % Get the clickedPixel
            pointClicked = round(evt.IntersectionPoint);
            pixelClicked = app.PixelCoordinates(pointClicked(1), pointClicked(2));
            
            % Extract pixel data
            app.CurrentKineticData = app.DataTable.(pixelClicked);
            
            % SINCE THE DATATABLE IS MAINTAINED ALL THROUGH THE PROJECT WE
            % CAN EXTRACT THE INDEX OF SELECTED PIXEL
            app.ActivePixel = find(app.DataTable.Properties.VariableNames == pixelClicked);
            
            
            % Set the current Time and Data respectively
            app.TimeAbove0 = app.DataTime(app.DataTime >= app.T0_Time);
            app.DataAbove0 = app.CurrentKineticData(app.DataTime >= app.T0_Time);


            % Plot trace
            plotKineticTrace(app);
            
        end
        % Function to plot kinetic trace. Will only plot app.CurrentKineticData
        function plotKineticTrace(app)
            % Throw error if called before data is available
            assert(~isempty(app.CurrentKineticData), "CurrentKineticData must be set first");
            % delete old plots
            delete(app.CurrentPlot);
            delete(app.T50_Line);
            delete(app.FittedPlot);
            

            % Obtain the current intensity
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            intensityatT0 = feval(app.fitFunctions{app.ActivePixel}, log10(app.T0_Time));
            % Obtain all intensities above t0
            % intensities = feval(app.DataFitFCN, app.FitX);
            intensities = feval(app.fitFunctions{app.ActivePixel}, app.fittedX);
            % get the time index
            %[~, t50_intensityIndex] = min(abs(intensities - intensityatT0/2));
            % Get the t_50time
            %t50_time = app.TimeAbove0(t50_intensityIndex);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            t50_intensity = intensityatT0/2;
            t50_time_evaluator = @(x)t50_intensity - app.fitFunctions{app.ActivePixel}(x);
            logt50_time= fzero(t50_time_evaluator, log10(app.T0_Time));
            t50_time = 10^logt50_time;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Plot line at y = 0
            yline(app.KinPlot, 0);
            % Hold the plot so we can plot two things
            hold(app.KinPlot, "on");
            
            % Plot the kinetic trace on plot
            app.CurrentPlot = plot(app.KinPlot, app.DataTime, app.CurrentKineticData, "Color", [1 0 0], "DisplayName","Selected Pixel"); 
            
            
            app.FittedPlot = plot(app.KinPlot, app.TimeAbove0, intensities, "LineStyle","--", "Color", [0.1 0.1 0.1], "DisplayName","Fitted");
            % Create the t50 line for the plot
            app.T50_Line = xline(app.KinPlot,t50_time, "DisplayName","");
            
            % Add legend to plot
            legend(app.KinPlot,app.CurrentPlot);
            
            % Turn off hold
            hold(app.KinPlot, "off");
            if ~isempty(app.CurrentSelectedAreaData)
                delete(app.SelectedRegionPlot);
                hold(app.KinPlot, "on");
                app.SelectedRegionPlot = plot(app.KinPlot, app.DataTime, app.CurrentSelectedAreaData, "Color",[0 0 1], "DisplayName","Rectangle Regions");
                hold(app.KinPlot, "off");
            end
        end
        
        % Function generates CurrentKineticData from drawn rectangles
        function getSelectedArea(app)
            % Create areas for all rectangles in DrawnRectangles
            drawnRects = app.DrawnRectangles;
            if ~isempty(drawnRects)
                % Extract positions
                positions = [drawnRects(:,:).Position];
                % Reshape into m x 4 array
                positions = reshape(positions, [4, (length(positions)/4)])';
                % Get bottom left co-ordinates
                bottoms = round([positions(:,1), positions(:,2)]);
                % Get top right
                tops = round([positions(:,1) + positions(:,3), positions(:,2)+positions(:,4)]);
    
                % Get the points covered by the rectangle
                %%%%% NOTE FOR FUTURE DEVELOPMENT
                %%%%% IF A METHOD CAN BE IMPLMEMENTED TO OBTAIN THE COVERED
                %%%%% POINTS MORE EFFICIENTLY PLEASE PLEASE PLEASE IMPLEMENT IT
                %%%%% THIS IS THE MOST INNEFIENT PART OF THE CODE!!!
                warning("off");
                pointsCovered = [];
                for point = 1:size(positions,1)
                    pointsCovered = [pointsCovered; reshape(app.PixelCoordinates(bottoms(point,1):tops(point,1), bottoms(point,2):tops(point,2)),[],1)];
                end
                warning("on");
                % Add covered pixels to selectedRegions
                % Extract the unique points to account for overlap, and to
                % prevent array from growing too large
                app.SelectedRegions = unique(pointsCovered);
    
                % Extract needed columns
                data = app.DataTable(:, ismember(app.DataTable.Properties.VariableNames, app.SelectedRegions));
                % assign data to the average of colums
                % CurrentSelectedAreaData = mean of all rows
                app.CurrentSelectedAreaData = mean(data{:,:}, 2);
                
                % ensure there is CurrentData
                if isempty(app.CurrentKineticData)
                    app.CurrentKineticData = app.CurrentSelectedAreaData;
                end
                
                % Once we have clean CurrentKineticData, we plot
                plotKineticTrace(app);
                
                % If we have rectArea, enable delete and add buttons
                if ~isempty(app.DrawnRectangles)
                    app.RemoveButton.Enable = "on";
                    app.AddRegionButton.Enable = "on";
                end
            else
                app.RemoveButton.Enable = "off";
                app.AddRegionButton.Enable = "off";
                app.SelectRegionButton.Enable = "on";
            end
        end
        
        function deleteRectangle(app, rectangle)
            % Extract rectangle id
            rectID = rectangle.UserData;
            % Filter out rectangle from list of drawn rectangles
            filterIndex = [app.DrawnRectangles.UserData];
            app.DrawnRectangles = app.DrawnRectangles(filterIndex ~= rectID);
            
            % Reduce the index
            app.rectangleID = app.rectangleID - 1;
            % Delete the rectangle
            delete(rectangle);
            % Redraw selected areas
            getSelectedArea(app);
        end
        
        function generateFitFCN(app)
            %% TIME AND DATA ARE ALREADY FROM lin2log_TAS
            if ~isempty(app.CurrentKineticData)
                LogLogTime = log10(app.TimeAbove0);
                
                [app.FitX, app.FitY] = prepareCurveData(LogLogTime, app.DataAbove0);
                
                StartFitTime = log10(app.T0_Time); %Time above to
                
                linfittype = fittype('a/(1+b*10^x)^c + d'); 
                
                fopt = fitoptions('Method','NonLinearLeastSquares');    % Define fitting options
                fopt.Exclude = app.FitX<StartFitTime;
                % These are the values correspondiong to a,b,c and d
                fopt.Lower = [-inf -inf 0 -inf];
                fopt.Upper = [inf inf 1 inf];
                fopt.StartPoint = [0.001 4 0.5 0];
                
                [app.DataFitFCN, ~] = fit(app.FitX,app.FitY,linfittype,fopt);
               
                
                plotKineticTrace(app);
            end
        end
        % Function to generate fit for all data
        function generateFitFCNS(app)
            % Filter time
            StartFitTime = log10(app.T0_Time); %Time above to
            % Defining the fit functionfor eval
            linfittype = fittype('a/(1+b*10^x)^c + d'); 
            
            fopt = fitoptions('Method','NonLinearLeastSquares');    % Define fitting options
            fopt.Exclude = app.FitX<StartFitTime;
            % These are the values correspondiong to a,b,c and d
            fopt.Lower = [-inf -inf 0 -inf];
            fopt.Upper = [inf inf 1 inf];
            fopt.StartPoint = [0.001 4 0.5 0];
        
            % Obtain the logarithm of all time above 0
            LogLogTime = log10(app.TimeAbove0);
            % Filter data to data above 0
            data = app.DataTable{app.DataTime >= app.T0_Time,:};
            % Prepare data of same size (same columns)
            [rowsize,colsize]=size(data);
            % It's less memory exhaustive to use loop
            % We know data size to expect
            app.fittedX = zeros(rowsize,1);
            % Keeps track of all fittedY
            app.fittedY = zeros(rowsize, colsize);
            app.fitFunctions = cell(colsize,1);
            %% TIME WITH TIK TOK
            %% 112*9 == 1008
            %% CHECK AGAIN INTO REPMAT AND ARRAYFUN-fit
            for column=1:1:colsize
                [app.fittedX, app.fittedY(:,column)] = prepareCurveData(LogLogTime, data(:,column));
                app.fitFunctions{column} = fit(app.fittedX, app.fittedY(:,column), linfittype,fopt);
            end
        end
    end
    % Accesible by dialog
    methods (Access = public)
        
        function passDataToMain(app, preDataTable, preDataTime, friendlyName, pixelCoordinates, fullName)
            % Assign loaded variables to app
            
            % Since we are working with logarithms, we only require the +ve
            % time and +ve time data
            
            % Perfom filtering of data
            positive_time = preDataTime(preDataTime > 0);
            positive_time_data = preDataTable(preDataTime > 0, :);
            
            app.DataTable = positive_time_data;
            app.DataTime = positive_time;
            app.FriendlyName = friendlyName;
            app.PixelCoordinates = pixelCoordinates;
            app.fullName = fullName;
            % Once data is loaded, time to start the functions
            % Plot the average data, and set the t0 time
            plotAverage(app);
            % Make T50 Line for data set
            app.TimeAbove0 = app.DataTime(app.DataTime >= app.T0_Time);
            app.DataAbove0 = app.DataTime(app.DataTime >= app.T0_Time);
            
            % Generate all the fit functions
            generateFitFCNS(app);
            
            % Plot the image
            plotImage(app);
            
            % Once image is made enable SchemeSwitch
            app.ColorSchemeSwitch.Enable = "on";
            
            % Enable loading data button
            app.LoadDataButton.Enable = "on";
            app.T0TimeEditField.Enable = "on";
            
            app.ExportDataButton.Enable = "on";
            
            app.MaxColorTime.Enable = "on";
            app.MinColorTime.Enable = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStartFcn(app)
            % Set AvgPlot and KinPlot to Log
            app.AvgPlot.XScale = "log";
            app.KinPlot.XScale = "log";
            
            % Disable graphs from being interactive i.e moving around
            disableDefaultInteractivity(app.AvgPlot);
            disableDefaultInteractivity(app.KinPlot);
            disableDefaultInteractivity(app.PixelPlot);
            
        end

        % Button pushed function: LoadDataButton
        function loadDataButtonPushed(app, event)
            % Disable button, until step size provided
            app.LoadDataButton.Enable = "off";
            % Open the dialog Box
            app.DataLoaderDialogApp = LoadDataDialogApp(app);
            % if data is loaded, change label
            if ~isempty(app.DataTable)
                app.LoadDataButton.Text = "Load New Data";
            end
        end

        % Close request function: UIFigure
        function onCloseFcn(app, event)
            % Delete DialogLoader and its variables
            delete(app.DataLoaderDialogApp);
            % Delete current app, and all variables
            delete(app);
        end

        % Value changed function: ColorSchemeSwitch
        function colorSchemeChange(app, event)
            % replot image if scheme changes
            plotImage(app);
            
        end

        % Button pushed function: SelectRegionButton
        function selectRegionBtnPressed(app, event)
            % Delete previous selected regions
            if ~isempty(app.SelectedRegions)
                app.SelectedRegions = [];
            end
            if ~isempty(app.DrawnRectangles)
                delete(app.DrawnRectangles);
                app.DrawnRectangles = [];
            end
            

            % Disable button to prevent errors
            app.SelectRegionButton.Enable = "off";
            app.RectDrawInfo.Visible = "on";
            
            % Start new drawing area
            drawnRectangle = drawrectangle(app.PixelPlot);
            % Ensure rectangle gets drawn
            if ~isempty(drawnRectangle.Position)
                % Addfunctionality to the rectangles
                % Add rectangle ID that corresponds to location on array
                drawnRectangle.UserData = app.rectangleID;
                % Increment rectangle ID
                app.rectangleID = app.rectangleID + 1;
                
                % Use the default menu, instead of creacting new menu
                % Delete handler
                drawnRectangle.ContextMenu.Children(1).MenuSelectedFcn = ...
                    @(~,~) deleteRectangle(app, drawnRectangle);
                
                % Add listener for when roi changes shape/moves
                % callBack will call plotKineticTrace with appropriate dataset
                addlistener(drawnRectangle, "ROIMoved", @(~, ~)getSelectedArea(app));
                % add to drawnRectangles
                app.DrawnRectangles = (drawnRectangle);
                
                % Get area
                getSelectedArea(app);
                % Enable the two buttons
                app.AddRegionButton.Visible = "on";
                app.RemoveButton.Visible = "on";
            end
            app.RectDrawInfo.Visible = "off";
            
        end

        % Button pushed function: AddRegionButton
        function addRegionBtnPressed(app, event)
            % Adds new rectangle without deleting old ones
            
            % Let user know they can cancel current drawing
            app.RectDrawInfo.Visible = "on";
            
            % Start new drawing area
            drawnRectangle = drawrectangle(app.PixelPlot);
            
            % Ensure the rectangle isn't empty
            if ~isempty(drawnRectangle.Position)    
                % Add functionality to the rectangles
                % Add rectangle ID that corresponds to location on array
                drawnRectangle.UserData = app.rectangleID;
                % Increment rectangle ID
                app.rectangleID = app.rectangleID + 1;
                
                % Use the default menu, instead of creacting new menu
                % Delete handler
                drawnRectangle.ContextMenu.Children(1).MenuSelectedFcn = ...
                    @(~,~) deleteRectangle(app, drawnRectangle);
                
                % Add listener for when roi changes shape/moves
                % callBack will call plotKineticTrace with appropriate dataset
                addlistener(drawnRectangle, "ROIMoved", @(src, ~)getSelectedArea(app));  
                
                % add to drawnRectangles
                app.DrawnRectangles = [app.DrawnRectangles; drawnRectangle];
                
                % Get area
                getSelectedArea(app);
            end
            app.RectDrawInfo.Visible = "off";
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch(key)
                case "escape"
                    % If first drawing cancelled
                    if isempty(app.DrawnRectangles)
                        app.SelectRegionButton.Enable = "on";
                        % Disable the two buttons
                        app.AddRegionButton.Visible = "off";
                        app.RemoveButton.Visible = "off";
                    end
                
            end
        end

        % Button pushed function: RemoveButton
        function deleteRectangles(app, event)
            % Delete all rectangles
            delete(app.DrawnRectangles);
            delete(app.SelectedRegionPlot);
            app.CurrentSelectedAreaData = [];
            app.DrawnRectangles = [];
            % Reset count
            app.rectangleID = 1;
            % Enable new region
            app.AddRegionButton.Visible = "off";
            app.RemoveButton.Visible = "off";
            app.SelectRegionButton.Enable = "on";
        end

        % Value changed function: T0TimeEditField
        function T0Changed(app, event)
            value = app.T0TimeEditField.Value;
            % Ensure input value is within range
            if value > max(app.DataTime)
                app.T0_Time = max(app.DataTime);
            elseif value < min(app.DataTime)
                app.T0_Time = min(app.DataTime);
            else
                % Ensure value input is linked to a value in table
                [~, t0_index] = min(abs(app.DataTime - value));
                app.T0_Time = app.DataTime(t0_index);
                % Set
                app.t0_line.Position = [app.T0_Time -1; app.T0_Time 1]; 
                app.t0_line_kinPlot.Value = app.T0_Time;
            end
            
            plotImage(app);
        end

        % Button pushed function: ExportDataButton
        function ExportDataPushed(app, event)
            % Write the image
            s = getframe(app.PixelPlot);
            mkdir(app.fullName+"\ExportedData");
            imwrite(s.cdata, app.fullName+"\ExportedData\ImagePlot.jpg");
            imageData = app.ImageSaveData;
            save(app.fullName+"\ExportedData\ImageData.mat", "imageData");
            selectedRegions = app.SelectedRegions;
            save(app.fullName+"\ExportedData\ROIPoints.mat", "selectedRegions");
            time = app.DataTime;
            pixelData = app.CurrentKineticData;
            ROIData = app.CurrentSelectedAreaData;
            save(app.fullName+"\ExportedData\KinPlotData.mat", "time", "pixelData", "ROIData");


        end

        % Value changed function: MaxColorTime, MinColorTime
        function TimeColorScaleChanged(app, event)
            minInd = app.MinColorTime.Value;
            maxInd = app.MaxColorTime.Value;
            
            if minInd > maxInd
                app.InfoLabel.Text = "Min must be less than max";
                app.InfoLabel.Visible = "on";
            else
                app.InfoLabel.Visible = "off";
                app.MinColorIndex = minInd;
                app.MaxColorIndex = maxInd;
            
                if minInd < min(app.DataTime)
                    app.MinColorIndex = min(app.DataTime);
                    app.MinColorTime.Value = app.MinColorIndex;
                end
                if maxInd > max(app.DataTime)
                    app.MaxColorIndex = max(app.DataTime);
                    app.MaxColorTime.Value = app.MaxColorIndex;
                end
            
            end
            

            plotImage(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {603, 603};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'TAM Analyzer';
            app.UIFigure.Icon = 'Icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @onCloseFcn, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.LoadDataButton = uibutton(app.GridLayout4, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.InfoLabel = uilabel(app.GridLayout4);
            app.InfoLabel.Visible = 'off';
            app.InfoLabel.Text = 'Loading...';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.ColorSchemeSwitch = uiswitch(app.GridLayout5, 'slider');
            app.ColorSchemeSwitch.Items = {'Intensity Derived', 'RGB'};
            app.ColorSchemeSwitch.ValueChangedFcn = createCallbackFcn(app, @colorSchemeChange, true);
            app.ColorSchemeSwitch.Enable = 'off';
            app.ColorSchemeSwitch.Tooltip = {'Change between color schemes'};
            app.ColorSchemeSwitch.Value = 'Intensity Derived';
            app.ColorSchemeSwitchLabel = uilabel(app.GridLayout5);
            app.ColorSchemeSwitchLabel.Text = 'Color Scheme';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.AddRegionButton = uibutton(app.GridLayout6, 'push');
            app.AddRegionButton.ButtonPushedFcn = createCallbackFcn(app, @addRegionBtnPressed, true);
            app.AddRegionButton.Enable = 'off';
            app.AddRegionButton.Visible = 'off';
            app.AddRegionButton.Text = 'Add Region';
            app.RemoveButton = uibutton(app.GridLayout6, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @deleteRectangles, true);
            app.RemoveButton.Icon = 'Delete.png';
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Visible = 'off';
            app.RemoveButton.Text = 'Remove';
            app.SelectRegionButton = uibutton(app.GridLayout4, 'push');
            app.SelectRegionButton.ButtonPushedFcn = createCallbackFcn(app, @selectRegionBtnPressed, true);
            app.SelectRegionButton.Enable = 'off';
            app.SelectRegionButton.Text = 'Select Region';
            app.RectDrawInfo = uilabel(app.GridLayout4);
            app.RectDrawInfo.Visible = 'off';
            app.RectDrawInfo.Text = 'Press ''esc'' to cancel draw';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.T0TimeEditFieldLabel = uilabel(app.GridLayout7);
            app.T0TimeEditFieldLabel.Enable = 'off';
            app.T0TimeEditFieldLabel.Text = 'T0 Time';
            app.T0TimeEditField = uieditfield(app.GridLayout7, 'numeric');
            app.T0TimeEditField.ValueChangedFcn = createCallbackFcn(app, @T0Changed, true);
            app.T0TimeEditField.Enable = 'off';
            app.ExportDataButton = uibutton(app.GridLayout4, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataPushed, true);
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.MinLabel = uilabel(app.GridLayout9);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.GridLayout9);
            app.MaxLabel.Text = 'Max';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.MinColorTime = uieditfield(app.GridLayout10, 'numeric');
            app.MinColorTime.ValueChangedFcn = createCallbackFcn(app, @TimeColorScaleChanged, true);
            app.MinColorTime.Enable = 'off';
            app.MaxColorTime = uieditfield(app.GridLayout10, 'numeric');
            app.MaxColorTime.ValueChangedFcn = createCallbackFcn(app, @TimeColorScaleChanged, true);
            app.MaxColorTime.Enable = 'off';
            app.ColorScaleTimesLabel = uilabel(app.GridLayout8);
            app.ColorScaleTimesLabel.Text = 'Color Scale Times';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.KinPlot = uiaxes(app.GridLayout2);
            title(app.KinPlot, 'Kinetic Plot')
            xlabel(app.KinPlot, 'Time')
            ylabel(app.KinPlot, 'ΔOD or %Abs')
            zlabel(app.KinPlot, 'Z')
            app.KinPlot.Visible = 'off';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.PixelPlot = uiaxes(app.GridLayout3);
            title(app.PixelPlot, 'Image')
            xlabel(app.PixelPlot, 'X')
            ylabel(app.PixelPlot, 'Y')
            app.PixelPlot.ZTick = [];
            app.PixelPlot.Visible = 'off';
            app.AvgPlot = uiaxes(app.GridLayout3);
            title(app.AvgPlot, 'Average Kinetic Trace')
            xlabel(app.AvgPlot, 'Time')
            ylabel(app.AvgPlot, 'ΔOD or %Abs')
            zlabel(app.AvgPlot, 'Z')
            app.AvgPlot.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TAM_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStartFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end